classdef Appprueba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LeerButton                     matlab.ui.control.Button
        SaludaButton                   matlab.ui.control.Button
        PruebaEditField                matlab.ui.control.EditField
        PruebaEditFieldLabel           matlab.ui.control.Label
        ModofuncionamientoButtonGroup  matlab.ui.container.ButtonGroup
        VelocidadButton                matlab.ui.control.RadioButton
        PosicionButton                 matlab.ui.control.RadioButton
        SaludoButton                   matlab.ui.control.RadioButton
        RpmssalidaGauge                matlab.ui.control.Gauge
        RpmssalidaGaugeLabel           matlab.ui.control.Label
        RefreshButton                  matlab.ui.control.Button
        GradosSlider                   matlab.ui.control.Slider
        GradosSliderLabel              matlab.ui.control.Label
        RPMsKnob                       matlab.ui.control.Knob
        RPMsKnobLabel                  matlab.ui.control.Label
        DetenercomunicacinButton       matlab.ui.control.Button
        IniciarcomunicacinButton       matlab.ui.control.Button
        ConexinLamp                    matlab.ui.control.Lamp
        ConexinLampLabel               matlab.ui.control.Label
        Baudios                        matlab.ui.control.NumericEditField
        BaudiosEditFieldLabel          matlab.ui.control.Label
        PuertoDropDown                 matlab.ui.control.DropDown
        PuertoDropDownLabel            matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Defino dos propiedades(variables)
        puerto; %Variable que almacena el puerto usado para conectar el arduino
      dataBuffer; %Variable usada como buffer de almacenamiento de los datos recibidos del Arduino
  %Defino dos variables color para usarlas más adelante
   GREEN_COLOR = [0 1 0];
        RED_COLOR = [1 0 0];
    %El vector UserData.Data tiene una capacidad maxima de 94 elementos, lo
    %limitamos a 50 para no pasarnos nunca. Declaramos un atributo para
    %poder hacer la media de 10 elementos de posición o velocidad que
    %recibimos del arduino

    nDatosmax=50; 
    nMedia=10; 
    dataMedia; 
    contador=1; 
    end


    methods (Access = private)
        
        %La siguiente funcion lee los datos y los almacena en un vector,
        %que es el vector UserData.Data, es un vector, propiedad del
        %atributo puerto. La funcion readline lo que nos permite es leer
        %hasta el final de línea
        %Luego aumentamos el conteo de datos leídos en 1. 
        
        function readSerialData(app,src,~)

         src.UserData.Data(app.puerto.UserData.Count)= readline(src);
          src.UserData.Count = src.UserData.Count + 1;  
           
          %Si llegamos al límite del vector UserData.Data, hacemos la media
          %de los valores recibidos hasta el momento
          if(src.UserData.Count>=nDatosmax)

          end
        end


%De Arduino recibo un string con el instante de tiempo en el que se ha
%tomado la posicion, el tiempo y cual es la posicion deseada


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %El argumento varargin es para poder poner argumentos variables
         
          %Declaramos una variable p, que asignamos a la lista de puertos disponibles, leídos
          %leídos con la funcion serialportlist
          p=serialportlist('available');

          %Asignamos los valores del drop down a la variable p
          app.PuertoDropDown.Items=p;

          %Inicializo el color del led de conexión a rojo

            app.ConexinLamp.Color=app.RED_COLOR;
          
            %Inicializamos las funciones del tiempo
            
%             tiempo=(0: app.TIME_STEP : 10)'; 
% 
%             app.t = timer;
%             app.t.TimerFcn = @app.timerFunction;
%             app.t.ExecutionMode = 'fixedRate';
%             app.t.Period = app.TIME_STEP;
%             app.t.BusyMode = 'drop';
%             

        
        end

        % Button pushed function: IniciarcomunicacinButton
        function IniciarcomunicacinButtonPushed(app, event)
            %Leemos y almacenamos en la variable baudios el valor que le
            %hemos puesto en la casilla baudios. Se establece la velocidad
            %de comunicación
            baudios=app.Baudios.Value;
           
            %Asignamos a la variable puerto, un canal de comunicación con
            %la función serialport. A esta función se le pasan como
            %argumentos el puerto seleccionado, elegido en el drop down de
            %puertos disponibles, y la velocidad de comunicación en
            %baudios
            app.puerto=serialport(app.PuertoDropDown.Value, 9600);
            
            %Configuramos el Terminator de la comunicación. Matlab leerá
            %mensaje hasta lo que hayamos establecido en esta
            %configuración, en este caso es final o salto de línea. 
            configureTerminator(app.puerto, "CR/LF");
            
            %Vaciamos "el cable" por si acaso había información antes de
            %que iniciasemos comunicación que no nos interesaba leer
            flush(app.puerto); 

            %Configuramos el callback de la función. Esta función lo que
            %hace es llamar a readSerialData, para que empiece la lectura
            %de información. El argumento byte,1 significa que leemos byte a
            %byte. La configuración se puede cambiar según las necesidades
            %que tengamos. Como de momento solo necesitamos leer un número
            %o un caracter, dejamos en un byte. 
            configureCallback(app.puerto,"terminator",@(src, event) readSerialData(app,src,event));
           
            %Inicializamos el contador de datos a 1, porque los vectores en
            %matlab empiezan en 1
            app.puerto.UserData.Count = 1;
           
            %Cambiamos el color del led a verde una vez establecida la
            %conexión
            app.ConexinLamp.Color=app.GREEN_COLOR;

            %Por último bloqueamos el botón
            set(app.IniciarcomunicacinButton,"Enable","off");
           

        end

        % Button pushed function: DetenercomunicacinButton
        function DetenercomunicacinButtonPushed(app, event)
            %Asignamos la variable puerto a Nada
            app.puerto=[];

            %Cambiamos el color del led a rojo
            app.ConexinLamp.Color=app.RED_COLOR;

            %Desbloqueamos el botón de la comunicación
            set(app.IniciarcomunicacinButton,"Enable","on");


        end

        % Value changed function: RPMsKnob
        function RPMsKnobValueChanged(app, event)
         %Primero convertimos el valor del Knob en un string, después lo
         %concatenamos a otro string, siguiendo el protocolo de
         %comunicación establecido. Este mensaje es el que se envía a
         %Matlab.
            
          rpms=num2str(app.RPMsKnob.Value); 
         
          %Mandar primero V y despues rpms
            writeline(app.puerto, "V");
          writeline(app.puerto,rpms);


        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            
            %Cuando se pulsa el boton de refresh, volvemos a buscar los
            %puertos disponibles, y se actualiza la lista
            p=serialportlist('available');
            app.PuertoDropDown.Items=p;


        end

        % Value changed function: GradosSlider
        function GradosSliderValueChanged(app, event)
       %Lo mismo que con el Knob de rpms
 
       grados=num2str(app.GradosSlider.Value); 

        writeline(app.puerto, "P");

       writeline(app.puerto,grados);
      set(app.GradosSlider,"Enable","off");
        if( app.puerto.UserData(app.contador)=="FP")
               set(app.GradosSlider,"Enable","on");
        end


        end

        % Button pushed function: SaludaButton
        function SaludaButtonPushed(app, event)
            writeline(app.puerto, "S");


        end

        % Value changed function: PruebaEditField
        function PruebaEditFieldValueChanged(app, event)
           

        end

        % Button pushed function: LeerButton
        function LeerButtonPushed(app, event)
                app.PruebaEditField.Value= app.puerto.UserData.Data(app.contador);
            app.contador=app.contador + 1; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Control Posición')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Control Velocidad')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PuertoDropDownLabel = uilabel(app.UIFigure);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.PuertoDropDown = uidropdown(app.UIFigure);
            app.BaudiosEditFieldLabel = uilabel(app.UIFigure);
            app.BaudiosEditFieldLabel.Text = 'Baudios';
            app.Baudios = uieditfield(app.UIFigure, 'numeric');
            app.ConexinLampLabel = uilabel(app.UIFigure);
            app.ConexinLampLabel.Text = 'Conexión';
            app.ConexinLamp = uilamp(app.UIFigure);
            app.IniciarcomunicacinButton = uibutton(app.UIFigure, 'push');
            app.IniciarcomunicacinButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarcomunicacinButtonPushed, true);
            app.IniciarcomunicacinButton.Text = 'Iniciar comunicación';
            app.DetenercomunicacinButton = uibutton(app.UIFigure, 'push');
            app.DetenercomunicacinButton.ButtonPushedFcn = createCallbackFcn(app, @DetenercomunicacinButtonPushed, true);
            app.DetenercomunicacinButton.Text = 'Detener comunicación';
            app.RPMsKnobLabel = uilabel(app.UIFigure);
            app.RPMsKnobLabel.Text = 'RPMs';
            app.RPMsKnob = uiknob(app.UIFigure, 'continuous');
            app.RPMsKnob.Limits = [0 1200];
            app.RPMsKnob.ValueChangedFcn = createCallbackFcn(app, @RPMsKnobValueChanged, true);
            app.GradosSliderLabel = uilabel(app.UIFigure);
            app.GradosSliderLabel.Text = 'Grados';
            app.GradosSlider = uislider(app.UIFigure);
            app.GradosSlider.Limits = [0 360];
            app.GradosSlider.MajorTicks = [0 90 180 270 360];
            app.GradosSlider.ValueChangedFcn = createCallbackFcn(app, @GradosSliderValueChanged, true);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.RpmssalidaGaugeLabel = uilabel(app.UIFigure);
            app.RpmssalidaGaugeLabel.Text = 'Rpms salida';
            app.RpmssalidaGauge = uigauge(app.UIFigure, 'circular');
            app.ModofuncionamientoButtonGroup = uibuttongroup(app.UIFigure);
            app.ModofuncionamientoButtonGroup.Title = 'Modo funcionamiento';
            app.SaludoButton = uiradiobutton(app.ModofuncionamientoButtonGroup);
            app.SaludoButton.Text = 'Saludo';
            app.SaludoButton.Value = true;
            app.PosicionButton = uiradiobutton(app.ModofuncionamientoButtonGroup);
            app.PosicionButton.Text = 'Posicion';
            app.VelocidadButton = uiradiobutton(app.ModofuncionamientoButtonGroup);
            app.VelocidadButton.Text = 'Velocidad';
            app.PruebaEditFieldLabel = uilabel(app.UIFigure);
            app.PruebaEditFieldLabel.Text = 'Prueba';
            app.PruebaEditField = uieditfield(app.UIFigure, 'text');
            app.PruebaEditField.ValueChangedFcn = createCallbackFcn(app, @PruebaEditFieldValueChanged, true);
            app.SaludaButton = uibutton(app.UIFigure, 'push');
            app.SaludaButton.ButtonPushedFcn = createCallbackFcn(app, @SaludaButtonPushed, true);
            app.SaludaButton.Text = 'Saluda';
            app.LeerButton = uibutton(app.UIFigure, 'push');
            app.LeerButton.ButtonPushedFcn = createCallbackFcn(app, @LeerButtonPushed, true);
            app.LeerButton.Text = 'Leer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Appprueba(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end