classdef PruebaComunicacionArduinoMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ControldePosicinPanel       matlab.ui.container.Panel
        DrawButton                  matlab.ui.control.Button
        PositionButton              matlab.ui.control.Button
        Slider                      matlab.ui.control.Slider
        SliderLabel                 matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        ComunicationInterfacePanel  matlab.ui.container.Panel
        SendButton                  matlab.ui.control.Button
        SendTerminal                matlab.ui.control.EditField
        RecieveTerminal             matlab.ui.control.EditField
        UserDataCount               matlab.ui.control.NumericEditField
        CounterButton               matlab.ui.control.Button
        ShowButton                  matlab.ui.control.Button
        ComunicationControlPanel    matlab.ui.container.Panel
        Lamp                        matlab.ui.control.Lamp
        BufferButton                matlab.ui.control.Button
        BufferCount                 matlab.ui.control.NumericEditField
        EndButton                   matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        device = [];
        btvCount = 1;
    end
    
    methods (Access = private)
        
        function readSerialData(app,src,~)
            data = read(src,1,"char");
            
            src.UserData.Data(app.device.UserData.Count)= data;
            src.UserData.Count = src.UserData.Count + 1;
        end

        function bloquearDibujo(app,~)
            set(app.DrawButton,'Enable','off');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.device = serialport(serialportlist,9600);
            configureTerminator(app.device,"CR/LF","CR/LF");
            flush(app.device);
            configureCallback(app.device,"byte",1,@(src, event) readSerialData(app,src,event));
            app.device.UserData.Count = 1;
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            app.device = [];
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            app.device.UserData.Data(app.btvCount)
            app.RecieveTerminal.Value = app.device.UserData.Data(app.btvCount);
            app.btvCount = app.btvCount + 1;
        end

        % Button pushed function: BufferButton
        function BufferButtonPushed(app, event)
            app.BufferCount.Value = app.device.NumBytesAvailable;
        end

        % Button pushed function: CounterButton
        function CounterButtonPushed(app, event)
            app.UserDataCount.Value = app.device.UserData.Count;
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            if (app.SendTerminal.Value ~= ' ')
                write(app.device,app.SendTerminal.Value,"char");
            end
        end

        % Button pushed function: PositionButton
        function PositionButtonPushed(app, event)
            
            writeline(app.device,"P");
            writeline(app.device,app.Slider.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ComunicationControlPanel = uipanel(app.UIFigure);
            app.ComunicationControlPanel.Title = 'Comunication Control';
            app.StartButton = uibutton(app.ComunicationControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.EndButton = uibutton(app.ComunicationControlPanel, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.BufferCount = uieditfield(app.ComunicationControlPanel, 'numeric');
            app.BufferButton = uibutton(app.ComunicationControlPanel, 'push');
            app.BufferButton.ButtonPushedFcn = createCallbackFcn(app, @BufferButtonPushed, true);
            app.BufferButton.Text = 'Buffer';
            app.Lamp = uilamp(app.ComunicationControlPanel);
            app.ComunicationInterfacePanel = uipanel(app.UIFigure);
            app.ComunicationInterfacePanel.Title = 'Comunication Interface';
            app.ShowButton = uibutton(app.ComunicationInterfacePanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.CounterButton = uibutton(app.ComunicationInterfacePanel, 'push');
            app.CounterButton.ButtonPushedFcn = createCallbackFcn(app, @CounterButtonPushed, true);
            app.CounterButton.Text = 'Counter';
            app.UserDataCount = uieditfield(app.ComunicationInterfacePanel, 'numeric');
            app.RecieveTerminal = uieditfield(app.ComunicationInterfacePanel, 'text');
            app.SendTerminal = uieditfield(app.ComunicationInterfacePanel, 'text');
            app.SendButton = uibutton(app.ComunicationInterfacePanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.ControldePosicinPanel = uipanel(app.UIFigure);
            app.ControldePosicinPanel.Title = 'Control de Posición';
            app.UIAxes = uiaxes(app.ControldePosicinPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderLabel = uilabel(app.ControldePosicinPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.ControldePosicinPanel);
            app.Slider.Limits = [0 360];
            app.PositionButton = uibutton(app.ControldePosicinPanel, 'push');
            app.PositionButton.ButtonPushedFcn = createCallbackFcn(app, @PositionButtonPushed, true);
            app.PositionButton.Text = 'Position';
            app.DrawButton = uibutton(app.ControldePosicinPanel, 'push');
            app.DrawButton.Text = 'Draw';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PruebaComunicacionArduinoMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end