classdef MariInterfaz1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ComunicacinTab               matlab.ui.container.Tab
        ComunicationControlPanel     matlab.ui.container.Panel
        Lamp                         matlab.ui.control.Lamp
        RefreshButton                matlab.ui.control.Button
        PuertoDropDown               matlab.ui.control.DropDown
        PuertoDropDownLabel          matlab.ui.control.Label
        DetenerButton                matlab.ui.control.Button
        IniciarButton                matlab.ui.control.Button
        Tab2                         matlab.ui.container.Tab
        PositionControlPanel         matlab.ui.container.Panel
        SolomodificavaloresdeM1M2YM6VERSION10Label  matlab.ui.control.Label
        PosicinngulosLabel           matlab.ui.control.Label
        EditField_12                 matlab.ui.control.NumericEditField
        EditField_11                 matlab.ui.control.NumericEditField
        EditField_10                 matlab.ui.control.NumericEditField
        EditField_9                  matlab.ui.control.NumericEditField
        EditField_8                  matlab.ui.control.NumericEditField
        EditField_7                  matlab.ui.control.NumericEditField
        EditField_6                  matlab.ui.control.NumericEditField
        EditField_5                  matlab.ui.control.NumericEditField
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.NumericEditField
        ErrorButton                  matlab.ui.control.Button
        TodosalavezLabel             matlab.ui.control.Label
        alavezM1YM2PARAPRUEBASLabel  matlab.ui.control.Label
        alavezM1YM2stepperM6PARAPRUEBASLabel  matlab.ui.control.Label
        Position3Button              matlab.ui.control.Button
        Position2Button              matlab.ui.control.Button
        Motor6M6Label_2              matlab.ui.control.Label
        Motor5M5Label_2              matlab.ui.control.Label
        Motor4M4Label_2              matlab.ui.control.Label
        Motor3M3Label_2              matlab.ui.control.Label
        Motor2M2Label_2              matlab.ui.control.Label
        Motor1M1Label_2              matlab.ui.control.Label
        Motor6M6Label                matlab.ui.control.Label
        Motor5M5Label                matlab.ui.control.Label
        Motor4M4Label                matlab.ui.control.Label
        Motor3M3Label                matlab.ui.control.Label
        Motor2M2Label                matlab.ui.control.Label
        Motor1M1Label                matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        ModoPosicionButton           matlab.ui.control.Button
        DibujarButton                matlab.ui.control.Button
        PositionButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        %Defino dos propiedades(variables)
        device = []; %Variable que almacena el puerto usado para conectar el arduino
        error1 =[]; 
        error2 = [];
        error6 = [];
        %He declarado 3 vectores para poder visualizar el error, tendré que
        %hacer más pruebas para ver si puedo gestionar todos los datos de
        %error desde un solo vector. Pero para hacer las pruebas este finde
        %15/10 declaro 3
        count=1;
        count2=1; 
        count6=1; 

         mensaje;


        posant=0; %Esta variable era para plotear, de momento no la usamos porque no la necesitamos 15/10 


        ; %Variable usada como buffer de almacenamiento de los datos recibidos del Arduino
  %Defino dos variables color para usarlas más adelante
       GREEN_COLOR = [0 1 0];
       RED_COLOR = [1 0 0];
    end
     methods (Access = private)
        
        function readSerialData(app,src,~)
            data = readline(src);
          src.UserData.Data(app.device.UserData.Count)= data;
%           app.error(app.device.UserData.Count)=app.posant; Esta línea nos
%           permitía guardar las posiciones de seguido para plotear el
%           escalón. De momenot no la necesitamos.
          src.UserData.Count = src.UserData.Count + 1;
          set(app.ShowButton,"Enable","on");
          set(app.DrawButton,"Enable","on");
          
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            %El argumento varargin es para poder poner argumentos variables
         
          %Declaramos una variable p, que asignamos a la lista de puertos disponibles, leídos
          %leídos con la funcion serialportlist
          p=serialportlist('available');

          %Asignamos los valores del drop down a la variable p
          app.PuertoDropDown.Items=p;

          %Inicializo el color del led de conexión a rojo

            app.ConexinLamp.Color=app.RED_COLOR;

        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
          
            app.device=serialport(app.PuertoDropDown.Value, 9600);
            configureTerminator(app.device,"CR/LF","CR/LF");
            flush(app.device);
            configureCallback(app.device,"terminator",@(src, event) readSerialData(app,src,event));
            app.device.UserData.Count = 1;

            %Bloqueamos partes sensibles
         
            set(app.IniciarButton,"Enable","off");


             app.Lamp.Color=app.GREEN_COLOR;


        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
              app.device = [];
            set(app.IniciarButton,"Enable","on");
           

        end

        % Button pushed function: Position2Button
        function Position2ButtonPushed(app, event)

              %%IMPORTANTE LEEER LOS COMENTARIOS


            %%APLICAR ESTE CODIGO SI SOLO ESTA PROGRAMADO EL ARDUINO PARA
            %%RECIBIR POSICIONES. SI ESTA PROGRAMADO PARA RECIBIR EL
            %%MENSAJE P Y ENTRAR EN MODO POSICION USAR EL CODIGO DE ABAJO
            
%              if ((app.EditField.Value)&(app.EditField_2) ~= ' ')
%                 app.mensaje=strcat('M1;', num2str(EditField.Value),
%                 'M2;', num2str(EditField_2.Value));
%                 writeline(app.device,app.mensaje);
%              end


%%CODIGO SI EL ARDUINO ESTA PROGRAMADO PARA ENTRAR EN UN BUCLE DE MODO
%%POSICION

            writeline(app.device,"P");
             app.mensaje=strcat('M1:', num2str(EditField.Value),',', 'M2:', num2str(EditField_2.Value),';');
            writeline(app.device,app.mensaje);
            app.error1(app.count)=app.EditField.Value;
            app.count=app.count+1;

            app.error2(app.count2)=app.EditField_2.Value;
            app.count2=app.count2+1; 
            
        end

        % Button pushed function: ErrorButton
        function ErrorButtonPushed(app, event)
           % if (1 ~= (strcmp(app.device.UserData.Data(end), 'M1') & strcmp(app.device.UserData.Data(end), 'M2') & strcmp(app.device.UserData.Data(end), 'M6')))
            
            
            app.EditField_12.Value=app.error1(end)-str2double(app.device.UserData.Data(end));
            app.EditField_13.Value=app.error2(end)-str2double(app.device.UserData.Data(end));

            app.EditField_13.Value=app.error6(end)-str2double(app.device.UserData.Data(end));

            %end
        end

        % Button pushed function: Position3Button
        function Position3ButtonPushed(app, event)
            
writeline(app.device,"P");
             app.mensaje=strcat('M1:', num2str(EditField.Value),',','M2:', num2str(EditField_2.Value),',', 'M6:', num2str(EditField_6.Value),';');
            writeline(app.device,app.mensaje);

            app.error1(app.count)=app.EditField.Value;
            app.count=app.count+1;

            app.error2(app.count2)=app.EditField_2.Value;
            app.count2=app.count2+1; 

            app.error6(app.count6)=app.EditField_6.Value; 
            app.count6=app.count6+1;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ComunicacinTab = uitab(app.TabGroup);
            app.ComunicacinTab.Title = 'Comunicación';
            app.ComunicationControlPanel = uipanel(app.ComunicacinTab);
            app.ComunicationControlPanel.Title = 'Comunication Control';
            app.IniciarButton = uibutton(app.ComunicationControlPanel, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.DetenerButton = uibutton(app.ComunicationControlPanel, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.PuertoDropDownLabel = uilabel(app.ComunicationControlPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.PuertoDropDown = uidropdown(app.ComunicationControlPanel);
            app.RefreshButton = uibutton(app.ComunicationControlPanel, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.Lamp = uilamp(app.ComunicationControlPanel);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.PositionControlPanel = uipanel(app.Tab2);
            app.PositionControlPanel.Title = 'Position Control';
            app.PositionButton = uibutton(app.PositionControlPanel, 'push');
            app.PositionButton.Text = 'Position';
            app.DibujarButton = uibutton(app.PositionControlPanel, 'push');
            app.DibujarButton.Text = 'Dibujar';
            app.ModoPosicionButton = uibutton(app.PositionControlPanel, 'push');
            app.ModoPosicionButton.Text = 'Modo Posicion';
            app.Image = uiimage(app.PositionControlPanel);
            app.Image.ImageSource = 'EsquemaRobot.jpg';
            app.Motor1M1Label = uilabel(app.PositionControlPanel);
            app.Motor1M1Label.Text = 'Motor 1: ''M1''';
            app.Motor2M2Label = uilabel(app.PositionControlPanel);
            app.Motor2M2Label.Text = 'Motor 2: ''M2''';
            app.Motor3M3Label = uilabel(app.PositionControlPanel);
            app.Motor3M3Label.Text = 'Motor 3: ''M3''';
            app.Motor4M4Label = uilabel(app.PositionControlPanel);
            app.Motor4M4Label.Text = 'Motor 4: ''M4''';
            app.Motor5M5Label = uilabel(app.PositionControlPanel);
            app.Motor5M5Label.Text = 'Motor 5: ''M5''';
            app.Motor6M6Label = uilabel(app.PositionControlPanel);
            app.Motor6M6Label.Text = 'Motor 6: ''M6''';
            app.Motor1M1Label_2 = uilabel(app.PositionControlPanel);
            app.Motor1M1Label_2.Text = 'Motor 1: ''M1''';
            app.Motor2M2Label_2 = uilabel(app.PositionControlPanel);
            app.Motor2M2Label_2.Text = 'Motor 2: ''M2''';
            app.Motor3M3Label_2 = uilabel(app.PositionControlPanel);
            app.Motor3M3Label_2.Text = 'Motor 3: ''M3''';
            app.Motor4M4Label_2 = uilabel(app.PositionControlPanel);
            app.Motor4M4Label_2.Text = 'Motor 4: ''M4''';
            app.Motor5M5Label_2 = uilabel(app.PositionControlPanel);
            app.Motor5M5Label_2.Text = 'Motor 5: ''M5''';
            app.Motor6M6Label_2 = uilabel(app.PositionControlPanel);
            app.Motor6M6Label_2.Text = 'Motor 6: ''M6''';
            app.Position2Button = uibutton(app.PositionControlPanel, 'push');
            app.Position2Button.ButtonPushedFcn = createCallbackFcn(app, @Position2ButtonPushed, true);
            app.Position2Button.Text = 'Position 2';
            app.Position3Button = uibutton(app.PositionControlPanel, 'push');
            app.Position3Button.ButtonPushedFcn = createCallbackFcn(app, @Position3ButtonPushed, true);
            app.Position3Button.Text = 'Position 3';
            app.alavezM1YM2stepperM6PARAPRUEBASLabel = uilabel(app.PositionControlPanel);
            app.alavezM1YM2stepperM6PARAPRUEBASLabel.Text = '2 a la vez M1 Y M2 + stepper M6. PARA PRUEBAS)';
            app.alavezM1YM2PARAPRUEBASLabel = uilabel(app.PositionControlPanel);
            app.alavezM1YM2PARAPRUEBASLabel.Text = '2 a la vez. M1 Y M2 PARA PRUEBAS)';
            app.TodosalavezLabel = uilabel(app.PositionControlPanel);
            app.TodosalavezLabel.Text = '(Todos a la vez)';
            app.ErrorButton = uibutton(app.PositionControlPanel, 'push');
            app.ErrorButton.ButtonPushedFcn = createCallbackFcn(app, @ErrorButtonPushed, true);
            app.ErrorButton.Text = 'Error';
            app.EditField = uieditfield(app.PositionControlPanel, 'numeric');
            app.EditField_2 = uieditfield(app.PositionControlPanel, 'numeric');
            app.EditField_3 = uieditfield(app.PositionControlPanel, 'numeric');
            app.EditField_4 = uieditfield(app.PositionControlPanel, 'numeric');
            app.EditField_5 = uieditfield(app.PositionControlPanel, 'numeric');
            app.EditField_6 = uieditfield(app.PositionControlPanel, 'numeric');
            app.EditField_7 = uieditfield(app.PositionControlPanel, 'numeric');
            app.EditField_8 = uieditfield(app.PositionControlPanel, 'numeric');
            app.EditField_9 = uieditfield(app.PositionControlPanel, 'numeric');
            app.EditField_10 = uieditfield(app.PositionControlPanel, 'numeric');
            app.EditField_11 = uieditfield(app.PositionControlPanel, 'numeric');
            app.EditField_12 = uieditfield(app.PositionControlPanel, 'numeric');
            app.PosicinngulosLabel = uilabel(app.PositionControlPanel);
            app.PosicinngulosLabel.Text = 'Posición ángulos';
            app.SolomodificavaloresdeM1M2YM6VERSION10Label = uilabel(app.PositionControlPanel);
            app.SolomodificavaloresdeM1M2YM6VERSION10Label.Text = 'Solo modifica valores de M1, M2 Y M6. VERSION1.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MariInterfaz1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end